classdef cell_categorize_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        CellCategoryPanel      matlab.ui.container.Panel
        CellChoiceListBox      matlab.ui.control.ListBox
        IndividualCell         matlab.ui.control.UIAxes
        ImagePanel             matlab.ui.container.Panel
        MainImage              matlab.ui.control.UIAxes
        HomePanel              matlab.ui.container.Panel
        LoadButton             matlab.ui.control.Button
        LoadButton_2           matlab.ui.control.Button
        FeedbackTextAreaLabel  matlab.ui.control.Label
        FeedbackTextArea       matlab.ui.control.TextArea
    end

    properties (Access = public)
        cellLocation;
        dataFolder;
        curIndex;
        curImage;
        fileNames;
    end
    
    methods (Access = private)
        % read data folder and start main process
        function getImage(app)
            app.fileNames = {dir([app.dataFolder  '/*.jpg']).name}';
            disp(app.fileNames)
            len = size(app.fileNames,1);
            if len == 0
                app.FeedbackTextArea.Value = "empty folder, please import data!";
            end
            for k = 1:len 
                app.curImage = k;
                img = imread([app.dataFolder '/' app.fileNames{k}]);
                locateCell(app, img)
                app.FeedbackTextArea.Value = "successfully read image and store cell locations!";
                processImage(app, img)
                if k == len
                    app.FeedbackTextArea.Value = "all images in this folder are finished!";
                end
            end
        end
        
        % show one image and start cell categorizing
        function processImage(app,img)
            imshow(img,'Parent',app.MainImage);
            [l, ~] = size(app.cellLocation);
            for i = 1:l
                rectangle(app.MainImage,'EdgeColor','white','Position',[app.cellLocation(i,1),app.cellLocation(i,2),app.cellLocation(i,3),app.cellLocation(i,4)])
            end
            app.cellLocation = [app.cellLocation zeros(l, 1)];
            app.curIndex = 1;
            while app.curIndex <= l
                individaulImage(app, img, app.curIndex)
                uiwait(app.UIFigure, 300)
                app.FeedbackTextArea.Value = "please select category for next cell!";
                if app.curIndex == l
                    app.FeedbackTextArea.Value = "this image is finished, loading next image!";
                    writematrix(app.cellLocation,strcat(app.fileNames{app.curImage},'.csv')) 
                end
                app.curIndex = app.curIndex + 1;
            end
        end
        
        % find and store cell locations
        function locateCell(app, img)
            grayimg = rgb2gray(img);
            bwimg = imbinarize(grayimg);
            [counts, ~] = imhist(bwimg);
            if counts(2)>counts(1)
                bwimg = imcomplement(bwimg);
            end
            bwimg = bwareaopen(bwimg, 50);
            bwimg = logical(bwimg);
            allregions = struct2cell(regionprops(bwimg));
            app.cellLocation = round(cell2mat(allregions(3,:)'));
            app.cellLocation = app.cellLocation((app.cellLocation(:,3).*app.cellLocation(:,4))>300,:);
        end
        
        % show image of one cell
        function individaulImage(app, img, i)
            rectangle(app.MainImage,'EdgeColor','green','Position',[app.cellLocation(i,1),app.cellLocation(i,2),app.cellLocation(i,3),app.cellLocation(i,4)])
            imshow(img(app.cellLocation(i,2):app.cellLocation(i,2)+app.cellLocation(i,4)-1,app.cellLocation(i,1):app.cellLocation(i,1)+app.cellLocation(i,3)-1,:),'parent',app.IndividualCell);
            %imshow(img(245:245+61,3127:3127+62,:),'Parent',app.IndividualCell);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.MainImage.Visible = 'off';
            app.MainImage.Colormap = gray(256);
            axis(app.MainImage, 'image');  
        end

        % Button pushed function: LoadButton
        function importData(app, event)
            app.dataFolder = uigetdir();
            getImage(app);
        end

        % Value changed function: CellChoiceListBox
        function cellChoice(app, event)
            value = app.CellChoiceListBox.Value; 
            [~, len] = size(value);
            if len == 1
                app.cellLocation(app.curIndex, 5) = str2num(value(1,1));
            else
                app.cellLocation(app.curIndex, 5) = str2num(strcat(value(1,1), value(1,2)));
            end
            app.CellChoiceListBox.Value = "0";
            i = app.curIndex;
            rectangle(app.MainImage,'EdgeColor','white','Position',[app.cellLocation(i,1),app.cellLocation(i,2),app.cellLocation(i,3),app.cellLocation(i,4)])
            uiresume(app.UIFigure);
        end

        % Button pushed function: LoadButton_2
        function previousCell(app, event)
            if app.curIndex >= 2
                app.curIndex = app.curIndex - 2;
                app.FeedbackTextArea.Value = "go to previous cell!";
                uiresume(app.UIFigure);
            else
                app.FeedbackTextArea.Value = "there is no previous one!";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Cell Categorize';
            app.CellCategoryPanel = uipanel(app.UIFigure);
            app.CellCategoryPanel.Title = 'Cell Category';
            app.CellChoiceListBox = uilistbox(app.CellCategoryPanel);
            app.CellChoiceListBox.Items = {'Please Select', 'Normocyte', 'Microcyte', 'Macrocyte', 'Gigantocyte', 'Target cells', 'Helmet cells', 'Sickle cells', 'Spherocytes', 'Ovalocytes', 'Tear drop cells', 'Polychromatic cells', 'Others', 'Unknown'};
            app.CellChoiceListBox.ItemsData = {'0', '1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13'};
            app.CellChoiceListBox.ValueChangedFcn = createCallbackFcn(app, @cellChoice, true);
            app.CellChoiceListBox.Value = '0';
            app.IndividualCell = uiaxes(app.CellCategoryPanel);
            app.IndividualCell.XTick = [];
            app.IndividualCell.XTickLabel = {'[ ]'};
            app.IndividualCell.YTick = [];
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImagePanel.Title = 'Image';
            app.MainImage = uiaxes(app.ImagePanel);
            app.MainImage.XTick = [];
            app.MainImage.XTickLabel = {'[ ]'};
            app.MainImage.YTick = [];
            app.HomePanel = uipanel(app.UIFigure);
            app.HomePanel.Title = 'Home';
            app.LoadButton = uibutton(app.HomePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @importData, true);
            app.LoadButton.Text = 'Import Data';
            app.LoadButton_2 = uibutton(app.HomePanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @previousCell, true);
            app.LoadButton_2.Text = 'Previous Cell';
            app.FeedbackTextAreaLabel = uilabel(app.HomePanel);
            app.FeedbackTextAreaLabel.Text = 'Feedback';
            app.FeedbackTextArea = uitextarea(app.HomePanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cell_categorize_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end